classdef ClientGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClientUIFigure        matlab.ui.Figure
        LoginButton           matlab.ui.control.Button
        UserNameLabel         matlab.ui.control.Label
        NameInput             matlab.ui.control.EditField
        PassWordLabel         matlab.ui.control.Label
        SecretInput           matlab.ui.control.EditField
        SignUpandLoginButton  matlab.ui.control.Button
    end


    properties (Access = private)
        player_name
        Server
        
    end
    
    properties (Access = public)
        game_window % Description
    end
    methods (Access = public)
    
        function results = name(app)
            results = app.player_name;
        end

        function results = server(app)
            results = app.Server;
        end
        
        function buttonEnableChange(app, bool)
            if(bool == true)
                app.LoginButton.Enable = 'on';
                app.SignUpandLoginButton.Enable = 'on';
            else
                app.LoginButton.Enable = 'off';
                app.SignUpandLoginButton.Enable = 'off';
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ser)
            %ser = load("server.mat");
            app.Server = ser;
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            if (isempty(app.NameInput.Value))
                errordlg('Incorrect username format.', 'Name Error');
                return;
            elseif (isempty(app.SecretInput.Value))
                errordlg('Incorrect password format.', 'Password Error');
                return;
            end
            success = app.Server.login(app.NameInput.Value, app.SecretInput.Value);     
            if (success)
                app.player_name = app.NameInput.Value;
                is_enter = app.Server.controller().enterPlayer(app.player_name);
                if (is_enter)
                    app.buttonEnableChange(false);
                    app.game_window = GameWindowGUI(app);
                else
                    msgbox('Current Room Is Full, please wait or choose another room');
                end
                
            else
                %msgbox('Username or Password error');
            end      
        end

        % Close request function: ClientUIFigure
        function ClientUIFigureCloseRequest(app, event)
            if (~isempty(app.game_window))
                delete(app.game_window)
            end
            delete(app)
            
        end

        % Button pushed function: SignUpandLoginButton
        function SignUpandLoginButtonPushed(app, event)
            if (isempty(app.NameInput.Value))
                errordlg('Incorrect username format.', 'Name Error');
                return;
            elseif (isempty(app.SecretInput.Value))
                errordlg('Incorrect password format.', 'Password Error');
                return;
            end
            success = app.Server.addAccount(app.NameInput.Value, app.SecretInput.Value);     
            if (success)
                app.player_name = app.NameInput.Value;
                is_enter = app.Server.controller().enterPlayer(app.player_name);
                if (is_enter)
                    app.buttonEnableChange(false);
                    app.game_window = GameWindowGUI(app);
                else
                    msgbox('Current Room Is Full, please wait or choose another room');
                end
                
            else
                %msgbox('Username or Password error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClientUIFigure = uifigure('Visible', 'off');
            app.ClientUIFigure.Name = 'Client';
            app.ClientUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClientUIFigureCloseRequest, true);
            app.LoginButton = uibutton(app.ClientUIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.UserNameLabel = uilabel(app.ClientUIFigure);
            app.UserNameLabel.Text = 'User Name';
            app.NameInput = uieditfield(app.ClientUIFigure, 'text');
            app.PassWordLabel = uilabel(app.ClientUIFigure);
            app.PassWordLabel.Text = 'Pass Word';
            app.SecretInput = uieditfield(app.ClientUIFigure, 'text');
            app.SignUpandLoginButton = uibutton(app.ClientUIFigure, 'push');
            app.SignUpandLoginButton.ButtonPushedFcn = createCallbackFcn(app, @SignUpandLoginButtonPushed, true);
            app.SignUpandLoginButton.Text = 'Sign Up and Login';
            % Show the figure after all components are created
            app.ClientUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClientGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClientUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClientUIFigure)
        end
    end
end