classdef GameWindowGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GameWindow                 matlab.ui.Figure
        Pass                       matlab.ui.control.Button
        Go                         matlab.ui.control.Button
        BidButton                  matlab.ui.control.Button
        NoBidButton                matlab.ui.control.Button
        Name_ThisLabel             matlab.ui.control.Label
        Point_This                 matlab.ui.control.NumericEditField
        RoomPlayersEditFieldLabel  matlab.ui.control.Label
        RoomPlayersEditField       matlab.ui.control.NumericEditField
        Front_Ready                matlab.ui.control.Label
        Next_Ready                 matlab.ui.control.Label
        This_Ready                 matlab.ui.control.Button
        CardsLeftEditFieldLabel    matlab.ui.control.Label
        Front_CardsLeft            matlab.ui.control.NumericEditField
        CardsLeftEditField_2Label  matlab.ui.control.Label
        Next_CardsLeft             matlab.ui.control.NumericEditField
        CardsLeftEditField_3Label  matlab.ui.control.Label
        This_CardsLeft             matlab.ui.control.NumericEditField
        This_Identity_Label        matlab.ui.control.Label
        This_Identity              matlab.ui.control.EditField
        Front_Identity_Label       matlab.ui.control.Label
        Front_Identity             matlab.ui.control.EditField
        Next_Identity_Label        matlab.ui.control.Label
        Next_Identity              matlab.ui.control.EditField
        IsWinLabel                 matlab.ui.control.Label
        EndGame                    matlab.ui.control.Button
        IsWaitLabel                matlab.ui.control.Label
        Name_FrontLabel            matlab.ui.control.Label
        Point_Front                matlab.ui.control.NumericEditField
        Name_NextLabel             matlab.ui.control.Label
        Point_Next                 matlab.ui.control.NumericEditField
        TimerThis                  matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Client
        Controller
        room_player_enter_listener
        room_player_exit_listener
        room_ready_listener
        start_game_listener   
        play_listener 
        next_listener
        win_listener
        local_cards = []
        y_baseline = 80  %手牌的基准y坐标
        x_baseline = 180 %手牌的基准x坐标
        
        y_playline = 200 %打出的牌的基准y坐标
        x_playline = 380 %打出的牌的基准x坐标
        
        y_playline_front = 400
        x_playline_front = 60
        
        y_playline_next = 400
        x_playline_next = 580
        x_interval = 20 %每张手牌之间的间隔
        card_size  = [40,60] %一张手牌的大小
        poker = ["3.PNG","4.PNG","5.PNG","6.PNG","7.PNG","8.PNG","9.PNG","10.PNG","11.PNG","12.PNG","13.PNG","1.PNG","2.PNG","joker1.PNG","joker2.PNG"]
        update_landlord_listener
        % 这个client玩家的手牌显示
        this_cards_view = {}
        this_play_cards_view = {}
        front_play_cards_view = {}
        next_play_cards_view = {}
        % 点击选的牌[],idx from 1 to 20
        selected = []
        
        Dizhu1 
        Dizhu2 
        Dizhu3 
        
        timeout 
        timewait = 5;
    end

    methods (Access = private)


        function updateRoomPlayersEnter(app,src,~)
            player_name = src.last_enter;
            thiss = find(strcmp(src.players, app.Name_ThisLabel.Text));
            new = find(strcmp(src.players, player_name));
            if (((thiss-new) == 1) || ((new-thiss)==2))
                app.Name_FrontLabel.Text = player_name;
                app.Front_Ready.Text = "No Ready";
                app.updatePointsView('Front');
            else
                app.Name_NextLabel.Text = player_name;
                app.Next_Ready.Text = "No Ready";
                app.updatePointsView('Next');
            end
            value = app.RoomPlayersEditField.Value;
            app.RoomPlayersEditField.Value = value+1;
        end
        
        function updateRoomPlayersExit(app,src,~)
            player_name = src.last_exit;
            if ( strcmp(app.Name_FrontLabel.Text, player_name))
                app.Name_FrontLabel.Text = "No Player";
                app.Front_Ready.Text = "No Ready";
                app.Point_Front.Value = 0;
            elseif ( strcmp(app.Name_NextLabel.Text, player_name))
                app.Name_NextLabel.Text = "No Player";
                app.Next_Ready.Text = "No Ready";
                app.Point_Next.Value = 0;
            end
            value = app.RoomPlayersEditField.Value;
            app.RoomPlayersEditField.Value = value-1;
        end
        
        function updateReadyView(app, ~, ~)
            k = strcmp(app.Client.server().controller().players,app.Name_FrontLabel.Text);
            is_ready = app.Client.server().controller().player_status(k);
            app.Front_Ready.Text = is_ready;
            
            k = strcmp(app.Client.server().controller().players,app.Name_ThisLabel.Text);
            is_ready = app.Client.server().controller().player_status(k);
            app.This_Ready.Text = is_ready;
            
            k = strcmp(app.Client.server().controller().players,app.Name_NextLabel.Text);
            is_ready = app.Client.server().controller().player_status(k);
            app.Next_Ready.Text = is_ready;
            
            
        end
        
        function initPlayers(app)
            app.Name_ThisLabel.Text = app.Client.name();
            num = app.Client.server().controller().number();
            if ( num > 1 )
                name = app.Client.name();
                players = app.Client.server().controller().players;
                stats = app.Client.server().controller().player_status;
                if (strcmp(players(1), name))
                    app.Name_NextLabel.Text = players(2);
                    app.Next_Ready.Text = stats(2);
                    app.Name_FrontLabel.Text = players(3);
                    app.Front_Ready.Text = stats(3);
                elseif (strcmp(players(2), name))
                    app.Name_NextLabel.Text = players(3);
                    app.Next_Ready.Text = stats(3);
                    app.Name_FrontLabel.Text = players(1);
                    app.Front_Ready.Text = stats(1);
                else
                    app.Name_NextLabel.Text = players(1);
                    app.Next_Ready.Text = stats(1);
                    app.Name_FrontLabel.Text = players(2);
                    app.Front_Ready.Text = stats(2);
                end
            end
            app.updatePointsView("All");
        end
        
        function startGameView(app, ~, ~)
            app.Front_Ready.Visible = 'off';
            app.Next_Ready.Visible = 'off';
            app.This_Ready.Visible = 'off';
            app.Controller.unready(app.Client.name());
            
            app.IsWaitLabel.Text = 'Playing';
            % 显示地主的三张牌
            dizhucards = app.Controller.dizhu_cards;
            app.Dizhu1.ImageSource = dizhucards(1) + ".PNG";
            app.Dizhu2.ImageSource = dizhucards(2) + ".PNG";
            app.Dizhu3.ImageSource = dizhucards(3) + ".PNG";
            app.Dizhu1.Visible = 'on';
            app.Dizhu2.Visible = 'on';
            app.Dizhu3.Visible = 'on';
            
            %第一发牌阶段
            app.updateCards();
     
            %%发牌结束更新手牌数, 起始各17张         
         
            %抢地主阶段
            app.NoBidButton.Visible = 'on';
            app.NoBidButton.Tag = '0';
            app.BidButton.Visible = 'on';
            app.BidButton.Tag = '1';
                                                      
        end
        
         function cardClicked(app, event)
            obj = event.Source;
            if (obj.Position(2:2) == app.y_baseline)
                obj.Position(2:2) = obj.Position(2:2)+20;
                app.selected  = [app.selected str2double(obj.Tag)];
            else
                obj.Position(2:2) = obj.Position(2:2)-20;
                app.selected(app.selected == str2double(obj.Tag)) = [];
            end
            disp(app.selected);
         end
         
        function updateLandlordView(app, src, ~)
            % 更新身份
            disp('id');disp(src.player_identity)
            k = strcmp(src.players, app.Name_FrontLabel.Text);
            id = src.player_identity(k); 
            app.Front_Identity.Value = id;
                 
            k = strcmp(src.players, app.Name_NextLabel.Text);
            id = src.player_identity(k); 
            app.Next_Identity.Value = id;
            
            k = strcmp(src.players, app.Client.name());
            id = src.player_identity(k); 
            app.This_Identity.Value = id;
            
            if (strcmp(id, "Landlord"))
                app.Go.Visible='on';
            end
            %更新手牌，手牌数
            app.updateCards()
        end
        
        function updateCards(app)
            %update front player
            app.Front_CardsLeft.Value = app.Client.server().controller().cardsLeft(app.Name_FrontLabel.Text);
            
            %update this player
            app.This_CardsLeft.Value = app.Client.server().controller().cardsLeft(app.Name_ThisLabel.Text);
            %update this player cards view
            cards = app.Client.server().controller().getCards(app.Client.name());
            
            size_t = length(cards);
            %ranks = arrayfun(@(x)  app.Client.server().controller().SUITED2RANK(x), cards);
            for i = 1 : size_t
                app.this_cards_view{i}.Tag = string(i);
                app.this_cards_view{i}.ImageSource = cards(i)+".PNG";
                app.this_cards_view{i}.Visible = 'on';
            end
            for i = (size_t+1):20
                app.this_cards_view{i}.Visible = 'off';
            end
            
            
            %update next player
            app.Next_CardsLeft.Value = app.Client.server().controller().cardsLeft(app.Name_NextLabel.Text);
        end
        
        function createCardsBackground(app,n)
            app.this_cards_view = cell(1,n);
            
            for i=1:n
                % 手牌区      
                im = uiimage(app.GameWindow);
                im.ImageClickedFcn = createCallbackFcn(app, @cardClicked, true);
                x = app.x_baseline+app.x_interval*i;
                im.Position = [x app.y_baseline app.card_size];
                im.Tag = "none";
                %app.poker(B(i)); 
                im.Visible = "off";
                app.this_cards_view{i} = im;
                 
                % 出牌区  3 个
                
                % this play cards region
                imp = uiimage(app.GameWindow);
                %im.ImageClickedFcn = createCallbackFcn(app, @cardClicked, true);
                x = app.x_playline+app.x_interval*i;
                imp.Position = [x app.y_playline app.card_size];
                %im.Tag = "none";
                imp.Visible = "off";
                app.this_play_cards_view{i} = imp;
                
                % front play cards region
                imp = uiimage(app.GameWindow);
                %im.ImageClickedFcn = createCallbackFcn(app, @cardClicked, true);
                x = app.x_playline_front+app.x_interval*i;
                imp.Position = [x app.y_playline_front app.card_size];
                %im.Tag = "none";
                imp.Visible = "off";
                app.front_play_cards_view{i} = imp;
                
                % next play cards region
                imp = uiimage(app.GameWindow);
                %im.ImageClickedFcn = createCallbackFcn(app, @cardClicked, true);
                x = app.x_playline_next+app.x_interval*i;
                imp.Position = [x app.y_playline_next app.card_size];
                %im.Tag = "none";
                imp.Visible = "off";
                app.next_play_cards_view{i} = imp;
                
            end    
            
        end
        
        function clearCardsBackground(app, n)
            for i=1:n
                % 手牌区      
                im = app.this_cards_view{i};
                im.Tag = "none";
                im.Visible = "off";
                im.Position(2:2) = app.y_baseline;
                 
                % 出牌区  3 个
                
                % this play cards region
                im = app.this_play_cards_view{i};
                im.Tag = "none";
                im.Visible = "off";
                
                % front play cards region
                im = app.front_play_cards_view{i};
                im.Tag = "none";
                im.Visible = "off";
                
                % next play cards region
                im = app.next_play_cards_view{i};
                im.Tag = "none";
                im.Visible = "off";
                
            end    
            
            app.Dizhu1.Visible = 'off';
            app.Dizhu2.Visible = 'off';
            app.Dizhu3.Visible = 'off';
        end
        
        function playView(app, ~, ~)
            % for this
            name = app.Client.name();
            if (strcmp(app.Controller.curr_player, name))
                % 更新手牌
                app.updateCards();
                % 关闭go pass 按钮
                app.Pass.Visible = 'off';
                app.Go.Visible = 'off';
                % 更新出牌
                cards = app.Controller.last_play{app.Controller.playIdx(name)};
                len = length(cards);
                %ranks = arrayfun(@(x)  app.Client.server().controller().SUITED2RANK(x), cards);
                for i = 1 : len
                    app.this_play_cards_view{i}.ImageSource = cards(i)+".PNG";
                    app.this_play_cards_view{i}.Visible = 'on';
                end
                for i = len+1 : 20
                    app.this_play_cards_view{i}.Visible = 'off';
                end
                return
            end
            % for front
            name = app.Name_FrontLabel.Text;
            if (strcmp(app.Controller.curr_player, name))
                % 更新出牌
                cards = app.Controller.last_play{app.Controller.playIdx(name)};
                len = length(cards);
                for i = 1 : len
                    app.front_play_cards_view{i}.ImageSource = cards(i) + ".PNG";
                    app.front_play_cards_view{i}.Visible = 'on';
                end
                for i = len+1 : 20
                    app.front_play_cards_view{i}.Visible = 'off';
                end
                return
            end
            % for next
            name = app.Name_NextLabel.Text;
            if (strcmp(app.Controller.curr_player, name))
                % 更新出牌
                cards = app.Controller.last_play{app.Controller.playIdx(name)};
                len = length(cards);
             
                for i = 1 : len
                    app.next_play_cards_view{i}.ImageSource = cards(i) + ".PNG";
                    app.next_play_cards_view{i}.Visible = 'on';
                end
                for i = len+1 : 20
                    app.next_play_cards_view{i}.Visible = 'off';
                end
                return
            end
        end
        
        function nextView(app, ~, ~)
            
            name = app.Client.name();
            if (strcmp(app.Controller.curr_player, name))
                app.Go.Visible = 'on';
                if (strcmp(name, app.Controller.lastCardWho()))
                    app.Pass.Visible = 'off';
                else
                    app.Pass.Visible = 'on';
                    disp('next');  
                    start(app.timeout);
                end      
            end
        end


        function winView(app, ~, ~)
            name = app.Client.name();
            if (strcmp(app.Controller.curr_player, name))
                app.IsWinLabel.Text = 'You Win!';
                app.IsWinLabel.Visible = 'on';
            else
                app.IsWinLabel.Text = 'You Lose';
                app.IsWinLabel.Visible = 'on';
            end
            app.EndGame.Visible = 'on';
            
        end
        
        function initReadyWindow(app)
            app.playVisibleChange(false);
            
            app.bidVisibleChange(false);
            
            app.IsWinLabel.Visible = 'off';
            app.EndGame.Visible = 'off';
            
            
            app.clearCardsBackground(20);
            app.selected = [];
            app.Controller.unready(app.Client.name());
            
            app.IsWaitLabel.Text = 'Waiting';
            
            app.readyVisibleChange(true);
            
            app.This_Identity.Value= '';
            app.Front_Identity.Value = '';
            app.Next_Identity.Value = '';
            
            app.This_CardsLeft.Value = 0;
            app.Front_CardsLeft.Value = 0;
            app.Next_CardsLeft.Value = 0;
            app.updatePointsView("All");


            app.This_Ready.Visible = 'on';
            app.updatePointsView('All');
        end
        
        function updatePointsView(app,area)
            if(strcmp(area, 'All'))     
                name = string(app.getName('Front'));
                app.Point_Front.Value = app.Client.server().points(name);
                app.Point_This.Value = app.Client.server().points(app.Client.name());
                app.Point_Next.Value = app.Client.server().points(app.getName('Next'));
            elseif strcmp(area, 'Front')
                app.Point_Front.Value = app.Client.server().points(app.getName(area));
            else
                app.Point_Next.Value = app.Client.server().points(app.getName(area));
            end
        end
        %=================Name=====================
        function ret = getName(app, where)
            if (strcmp(where, 'Front'))
                ret = app.Name_FrontLabel.Text;
            else
                ret = app.Name_NextLabel.Text;
            end
        end
        
        %=============Button, Label Visible=============
        function bidVisibleChange(app,bool)
            if(bool==true)
                app.BidButton.Visible = 'on';
                app.NoBidButton.Visible = 'on';
            else
                app.BidButton.Visible = 'off';
                app.NoBidButton.Visible = 'off';
            end
        end
        
        function playVisibleChange(app,bool)
            if(bool==true)
                app.Pass.Visible = 'on';
                app.Go.Visible = 'on';
            else
                app.Pass.Visible = 'off';
                app.Go.Visible = 'off';
            end
        end
        
        function readyVisibleChange(app,bool)
            if(bool==true)
                app.This_Ready.Visible = 'on';
                app.Front_Ready.Visible = 'on';
                app.Next_Ready.Visible = 'on';
            else
                app.This_Ready.Visible = 'off';
                app.Front_Ready.Visible = 'off';
                app.Next_Ready.Visible = 'off';
            end
        end
        function statusVisibleChange(app, bool)
            if(bool==true)
                app.IsWaitLabel.Visible = 'on';
            
            else
                app.IsWaitLabel.Visible = 'off';
            end
        end
        function winVisibleChange(app,bool)
            if(bool==true)
                app.IsWinLabel.Visible = 'on';
                app.EndGame.Visible = 'on';
                
            else
                app.IsWinLabel.Visible = 'off';
                app.EndGame.Visible = 'off';
            end
        end
        
        %=============delete listener=========
        function deleteListener(app)
            delete(app.room_player_enter_listener);
            delete(app.room_player_exit_listener);
            delete(app.room_ready_listener);
            delete(app.start_game_listener);
            delete(app.play_listener);
            delete(app.next_listener);
            delete(app.win_listener);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, client)
            % Assign attr
            app.Client = client;
            app.Controller = client.server().controller();
            % timeout timer
            function timerCallbackFcn(~, ~, tapp)    
                 if (tapp.TimerThis.Value == 0)
                     tapp.TimerThis.Value = tapp.timewait;
                 elseif (tapp.TimerThis.Value == 1)
                     tapp.TimerThis.Value = 0;
                     tapp.Controller.passPlay();
                     stop(tapp.timeout);
                     
                     
                 else 
                     tapp.TimerThis.Value = tapp.TimerThis.Value-1;
                end
                
            end
            app.timeout= timer('TimerFcn',{@timerCallbackFcn, app},"StartDelay",0,"Period",1,'ExecutionMode',"fixedSpacing");
            
            % status set
            app.IsWaitLabel.Text = 'Waiting';
            app.IsWaitLabel.Visible = 'on';
            
            app.playVisibleChange(false);
            
            app.winVisibleChange(false);
            
            app.Dizhu1 = uiimage(app.GameWindow,"ImageSource",'',"Position",[394,550,40,60],"Visible","off");
            app.Dizhu2 = uiimage(app.GameWindow,"ImageSource",'',"Position",[455,550,40,60],"Visible","off");
            app.Dizhu3 = uiimage(app.GameWindow,"ImageSource",'',"Position",[515,550,40,60],"Visible","off");
          
            
            app.initPlayers();
            app.This_Ready.Text = "No Ready";
            
            app.RoomPlayersEditField.Value = app.Client.server().controller().number();
            app.createCardsBackground(20);
            
            %listeners init
            app.room_player_enter_listener = listener(app.Client.server().controller(),'PlayerEnter', @app.updateRoomPlayersEnter);
            app.room_player_exit_listener = listener(app.Client.server().controller(),'PlayerExit', @app.updateRoomPlayersExit);
            app.room_ready_listener = listener(app.Client.server().controller(),'ReadyChanged', @app.updateReadyView);
            app.start_game_listener = listener(app.Client.server().controller(), 'StartGame', @app.startGameView);
            app.update_landlord_listener = listener(app.Client.server().controller(),'LandlordAppear', @app.updateLandlordView);
            app.play_listener = listener(app.Client.server().controller(),'Play', @app.playView);
            app.next_listener = listener(app.Client.server().controller(),'Next', @app.nextView);
            app.win_listener = listener(app.Client.server().controller(),'Win', @app.winView);
        end

        % Button pushed function: Pass
        function PassButtonPushed(app, event)
            stop(app.timeout);
            app.TimerThis.Value = 0;       
            app.Controller.passPlay();
                
        end

        % Button pushed function: Go
        function GoButtonPushed(app, event)
            select = app.selected;
            if (app.Client.server().controller().tryPlay(app.selected))
                app.selected = [];    
                for i = 1 : length(select)
                    obj = app.this_cards_view{select(i)};
                    obj.Position(2:2) = obj.Position(2:2)-20;
                    
                end
                stop(app.timeout);
                app.TimerThis.Value = 0;
            else
                % 提醒出牌不符合规则
                msgbox('出牌不符合规则，请重新出牌');
            end
            
        end

        % Button pushed function: BidButton, NoBidButton
        function BidButtonPushed(app, event)
            app.NoBidButton.Visible = 'off';
            app.BidButton.Visible = 'off';
            point = str2double(event.Source.Tag);
            switch point
                case 0
                    app.Client.server().controller().bid(app.Client.name(), 0);             
                case 1
                    app.Client.server().controller().bid(app.Client.name(), 1);
                otherwise
                    disp("Error when bid");
            end
        end

        % Value changed function: Point_This
        function Point_ThisValueChanged(app, event)
            %value = app.Point_This.Value;
            app.Point_This.Value = app.client_.points();
            app.Point_This.Editable = 'off';
            %app.Point_This.
        end

        % Close request function: GameWindow
        function GameWindowCloseRequest(app, event)
            app.Client.server().controller().exitPlayer(app.Client.name());
            app.deleteListener();
            app.Client.buttonEnableChange(true);
            delete(app)
            
        end

        % Callback function
        function CardClicked(app, event)
            if (app.Card.Position(2:2) == app.y_baseline)
                app.Card.Position(2:2) = app.Card.Position(2:2)+20;
            else
                app.Card.Position(2:2) = app.Card.Position(2:2)-20;
            end
        end

        % Button pushed function: This_Ready
        function This_ReadyButtonPushed(app, event)
            text = app.This_Ready.Text;
            if (strcmp(text, "No Ready"))
                app.Client.server().controller().ready(app.Client.name());
            else
                app.Client.server().controller().unready(app.Client.name());
            end
        end

        % Button pushed function: EndGame
        function EndGameButtonPushed(app, event)
            app.initReadyWindow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GameWindow = uifigure('Visible', 'off');
            app.GameWindow.Name = 'UI Figure';
            app.GameWindow.CloseRequestFcn = createCallbackFcn(app, @GameWindowCloseRequest, true);
            app.Pass = uibutton(app.GameWindow, 'push');
            app.Pass.ButtonPushedFcn = createCallbackFcn(app, @PassButtonPushed, true);
            app.Pass.Visible = 'off';
            app.Pass.Text = 'Pass';
            app.Go = uibutton(app.GameWindow, 'push');
            app.Go.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.Go.Visible = 'off';
            app.Go.Text = 'Go';
            app.BidButton = uibutton(app.GameWindow, 'push');
            app.BidButton.ButtonPushedFcn = createCallbackFcn(app, @BidButtonPushed, true);
            app.BidButton.Visible = 'off';
            app.BidButton.Text = 'Bid Landlord';
            app.NoBidButton = uibutton(app.GameWindow, 'push');
            app.NoBidButton.ButtonPushedFcn = createCallbackFcn(app, @BidButtonPushed, true);
            app.NoBidButton.Visible = 'off';
            app.NoBidButton.Text = 'Not Bid';
            app.Name_ThisLabel = uilabel(app.GameWindow);
            app.Name_ThisLabel.Text = 'No Player';
            app.Point_This = uieditfield(app.GameWindow, 'numeric');
            app.Point_This.ValueDisplayFormat = '%.0f';
            app.Point_This.ValueChangedFcn = createCallbackFcn(app, @Point_ThisValueChanged, true);
            app.Point_This.Editable = 'off';
            app.RoomPlayersEditFieldLabel = uilabel(app.GameWindow);
            app.RoomPlayersEditFieldLabel.Text = 'Room Players';
            app.RoomPlayersEditField = uieditfield(app.GameWindow, 'numeric');
            app.RoomPlayersEditField.Limits = [0 3];
            app.RoomPlayersEditField.ValueDisplayFormat = '%.0f';
            app.RoomPlayersEditField.Editable = 'off';
            app.Front_Ready = uilabel(app.GameWindow);
            app.Front_Ready.Text = 'No Ready';
            app.Next_Ready = uilabel(app.GameWindow);
            app.Next_Ready.Text = 'No Ready';
            app.This_Ready = uibutton(app.GameWindow, 'push');
            app.This_Ready.ButtonPushedFcn = createCallbackFcn(app, @This_ReadyButtonPushed, true);
            app.This_Ready.Text = 'ReadyButton';
            app.CardsLeftEditFieldLabel = uilabel(app.GameWindow);
            app.CardsLeftEditFieldLabel.Text = 'Cards Left';
            app.Front_CardsLeft = uieditfield(app.GameWindow, 'numeric');
            app.Front_CardsLeft.Editable = 'off';
            app.CardsLeftEditField_2Label = uilabel(app.GameWindow);
            app.CardsLeftEditField_2Label.Text = 'Cards Left';
            app.Next_CardsLeft = uieditfield(app.GameWindow, 'numeric');
            app.Next_CardsLeft.Editable = 'off';
            app.CardsLeftEditField_3Label = uilabel(app.GameWindow);
            app.CardsLeftEditField_3Label.Text = 'Cards Left';
            app.This_CardsLeft = uieditfield(app.GameWindow, 'numeric');
            app.This_CardsLeft.Editable = 'off';
            app.This_Identity_Label = uilabel(app.GameWindow);
            app.This_Identity_Label.Text = 'Identity';
            app.This_Identity = uieditfield(app.GameWindow, 'text');
            app.This_Identity.Editable = 'off';
            app.Front_Identity_Label = uilabel(app.GameWindow);
            app.Front_Identity_Label.Text = 'Identity';
            app.Front_Identity = uieditfield(app.GameWindow, 'text');
            app.Front_Identity.Editable = 'off';
            app.Next_Identity_Label = uilabel(app.GameWindow);
            app.Next_Identity_Label.Text = 'Identity';
            app.Next_Identity = uieditfield(app.GameWindow, 'text');
            app.Next_Identity.Editable = 'off';
            app.IsWinLabel = uilabel(app.GameWindow);
            app.IsWinLabel.Text = 'Is Win';
            app.EndGame = uibutton(app.GameWindow, 'push');
            app.EndGame.ButtonPushedFcn = createCallbackFcn(app, @EndGameButtonPushed, true);
            app.EndGame.Text = 'Game Over';
            app.IsWaitLabel = uilabel(app.GameWindow);
            app.IsWaitLabel.Text = 'Waiting';
            app.Name_FrontLabel = uilabel(app.GameWindow);
            app.Name_FrontLabel.Text = 'No Player';
            app.Point_Front = uieditfield(app.GameWindow, 'numeric');
            app.Name_NextLabel = uilabel(app.GameWindow);
            app.Name_NextLabel.Text = 'No Player';
            app.Point_Next = uieditfield(app.GameWindow, 'numeric');
            app.TimerThis = uieditfield(app.GameWindow, 'numeric');
            app.TimerThis.Editable = 'off';
            % Show the figure after all components are created
            app.GameWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GameWindowGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GameWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GameWindow)
        end
    end
end