classdef Start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DouDiZhu           matlab.ui.Figure
        OpenClientButton   matlab.ui.control.Button
        DouDiZhuGameLabel  matlab.ui.control.Label
    end


    properties (Access = public)  
        Server
        ClientGUI
        %Server = Server();% Description
       
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Server_now    
            load('Server.mat','Server_now');
            
            app.Server = Server_now;
            addpath('img');
            addpath('enums');
        end

        % Button pushed function: OpenClientButton
        function OpenClientButtonPushed(app, event)
            app.ClientGUI = ClientGUI(app.Server);
        end

        % Close request function: DouDiZhu
        function DouDiZhuCloseRequest(app, event)
            app.Server.controller.cleanPlayers();   
            
            Server_now = app.Server;
            save('Server.mat', 'Server_now');    
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DouDiZhu = uifigure('Visible', 'off');
            app.DouDiZhu.Name = 'Doudizhu';
            app.DouDiZhu.CloseRequestFcn = createCallbackFcn(app, @DouDiZhuCloseRequest, true);
            app.OpenClientButton = uibutton(app.DouDiZhu, 'push');
            app.OpenClientButton.ButtonPushedFcn = createCallbackFcn(app, @OpenClientButtonPushed, true);
            app.OpenClientButton.Text = 'Open Client';
            app.DouDiZhuGameLabel = uilabel(app.DouDiZhu);
            app.DouDiZhuGameLabel.Text = 'DouDiZhu Game';
            % Show the figure after all components are created
            app.DouDiZhu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DouDiZhu)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DouDiZhu)
        end
    end
end